classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        PilihGambarMenu              matlab.ui.container.Menu
        ResetMenu                    matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        PenyesuaianTab               matlab.ui.container.Tab
        FilterButtonGroup            matlab.ui.container.ButtonGroup
        FilterPererataanButton       matlab.ui.control.RadioButton
        FilterBatasButton            matlab.ui.control.RadioButton
        TidakAdaButton_5             matlab.ui.control.RadioButton
        FilterMedianButton           matlab.ui.control.RadioButton
        SimpanFileButton             matlab.ui.control.StateButton
        SimpanPerubahanButton        matlab.ui.control.StateButton
        PenyesuaianCitraButtonGroup  matlab.ui.container.ButtonGroup
        KontrasTinggiButton_2        matlab.ui.control.RadioButton
        BlueButton                   matlab.ui.control.RadioButton
        GreenButton                  matlab.ui.control.RadioButton
        RedButton                    matlab.ui.control.RadioButton
        BrightnessRendahButton       matlab.ui.control.RadioButton
        BrightnessTinggiButton       matlab.ui.control.RadioButton
        KontrasRendahButton          matlab.ui.control.RadioButton
        SharpeningButton             matlab.ui.control.RadioButton
        GrayscaleButton              matlab.ui.control.RadioButton
        TidakAdaButton               matlab.ui.control.RadioButton
        TransformasiTab              matlab.ui.container.Tab
        SimpanFileButton_2           matlab.ui.control.StateButton
        SimpanPerubahanButton_2      matlab.ui.control.StateButton
        SesuaikanUkuranButtonGroup   matlab.ui.container.ButtonGroup
        EditField                    matlab.ui.control.EditField
        ResolusiBaruSlider           matlab.ui.control.Slider
        ResolusiBaruSliderLabel      matlab.ui.control.Label
        TidakAdaButton_4             matlab.ui.control.RadioButton
        FlipButtonGroup              matlab.ui.container.ButtonGroup
        efekmati1efekhidupLabel      matlab.ui.control.Label
        InputSpinner_2               matlab.ui.control.Spinner
        InputSpinner_2Label          matlab.ui.control.Label
        VerticalFlipButton           matlab.ui.control.RadioButton
        InputSpinner                 matlab.ui.control.Spinner
        InputSpinnerLabel            matlab.ui.control.Label
        HorizontalFlipButton         matlab.ui.control.RadioButton
        TidakAdaButton_3             matlab.ui.control.RadioButton
        RotasiButtonGroup            matlab.ui.container.ButtonGroup
        NilaiderajatSpinner_3        matlab.ui.control.Spinner
        NilaiderajatSpinner_3Label   matlab.ui.control.Label
        RotasiKananButton            matlab.ui.control.RadioButton
        NilaiderajatSpinner          matlab.ui.control.Spinner
        NilaiderajatSpinnerLabel     matlab.ui.control.Label
        RotasiKiriButton             matlab.ui.control.RadioButton
        TidakAdaButton_2             matlab.ui.control.RadioButton
        RightPanel                   matlab.ui.container.Panel
        InformasiResolusiPanel       matlab.ui.container.Panel
        AsliEditField                matlab.ui.control.EditField
        AsliEditFieldLabel           matlab.ui.control.Label
        ResizeEditField              matlab.ui.control.EditField
        ResizeEditFieldLabel         matlab.ui.control.Label
        Image_2                      matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        CitraTerprosesLabel          matlab.ui.control.Label
        CitraAsliLabel               matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        X % Description
        Y
        s1
        s3
        name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: PilihGambarMenu
        function PilihGambarMenuSelected(app, event)
            app.TidakAdaButton.Value=1;
            [filename, filepath] = uigetfile("*.*");
            drawnow
            figure(app.UIFigure)
            if ~isequal(filename,0)
                cla(app.UIAxes,'reset')
                cla(app.UIAxes_2,"reset")
                Img = imread(fullfile(filepath,filename));
                imshow(Img,'Parent',app.UIAxes)
                title(app.UIAxes,'Citra Asli')
                imshow(Img,'Parent',app.UIAxes_2)
                title(app.UIAxes_2,'Citra Terproses')
                app.Image.Visible="off"
                app.Image_2.Visible="off"
                app.CitraAsliLabel.Visible="off"
                app.CitraTerprosesLabel.Visible="off"

                app.X = Img;
            else
                return
            end
            app.KontrasTinggiButton_2.Enable = true;
            app.KontrasRendahButton.Enable = true;
            app.BlueButton.Enable = true;
            app.GreenButton.Enable = true;
            app.RedButton.Enable = true;
            app.BrightnessRendahButton.Enable = true;
            app.BrightnessTinggiButton.Enable = true;
            app.SharpeningButton.Enable = true;
            app.GrayscaleButton.Enable = true;
            app.SimpanFileButton.Enable = true;
            app.SimpanPerubahanButton.Enable = true;
            app.SimpanFileButton_2.Enable = true;
            app.SimpanPerubahanButton_2.Enable = true;
            app.NilaiderajatSpinner.Enable = true;
            app.NilaiderajatSpinner_3.Enable = true;
            app.InputSpinner.Enable = true;
            app.InputSpinner_2.Enable = true;
            app.InformasiResolusiPanel.Visible='off';
            app.FilterBatasButton.Enable = true;
            app.FilterMedianButton.Enable = true;
            app.FilterPererataanButton.Enable = true;

            infoI1 = imfinfo(fullfile(filepath,filename));
            width = string(infoI1.Width);
            height = string(infoI1.Height);
            app.AsliEditField.Value = width + 'x' + height;
            app.name = filename;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            cla(app.UIAxes,'reset')
            cla(app.UIAxes_2,"reset")
            app.UIAxes.Visible="off"
            app.UIAxes_2.Visible="off"
            app.CitraAsliLabel.Visible="on"
            app.CitraTerprosesLabel.Visible="on"
            app.Image.Visible="on"
            app.Image_2.Visible="on"
            app.TidakAdaButton.Value=1;
            app.KontrasTinggiButton_2.Enable = false;
            app.KontrasRendahButton.Enable = false;
            app.BlueButton.Enable = false;
            app.GreenButton.Enable = false;
            app.RedButton.Enable = false;
            app.BrightnessRendahButton.Enable = false;
            app.BrightnessTinggiButton.Enable = false;
            app.SharpeningButton.Enable = false;
            app.GrayscaleButton.Enable = false;
            app.SimpanFileButton.Enable = false;
            app.SimpanPerubahanButton.Enable = false;
            app.NilaiderajatSpinner.Enable = false;
            app.InformasiResolusiPanel.Visible='off';
            app.FilterBatasButton.Enable = false;
            app.FilterMedianButton.Enable = false;
            app.FilterPererataanButton.Enable = false;
            app.SimpanFileButton_2.Enable = false;
            app.SimpanPerubahanButton_2.Enable = false;
        end

        % Selection changed function: PenyesuaianCitraButtonGroup
        function PenyesuaianCitraButtonGroupSelectionChanged(app, event)
            selectedButton = app.PenyesuaianCitraButtonGroup.SelectedObject.Text
            
            Img = app.X;
            R = Img(:,:,1);
            G = Img(:,:,2);
            B = Img(:,:,3);

                switch selectedButton
                    case 'Tidak Ada'
                        imshow(Img,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Tanpa Filter')
                        app.Y = Img;
                    case 'Grayscale'
                        Img_gray = rgb2gray(Img);
                        imshow(Img_gray,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Grayscale')
                        app.Y = Img_gray;
                    case 'Red'
                        Red = cat(3,R,G*0,B*0);
                        imshow(Red,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Channel Red')
                        app.Y = Red;
                    case 'Green'
                        Green = cat(3,R*0,G,B*0);
                        imshow(Green,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Channel Green')
                        app.Y = Green;
                    case 'Blue'
                        Blue = cat(3,R*0,G*0,B);
                        imshow(Blue,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Channel Blue')
                        app.Y = Blue;
                    case 'Sharpening'
                        Img_shrp = imsharpen(Img,"Radius",2,"Amount",1);
                        imshow(Img_shrp,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Sharpened')
                        app.Y = Img_shrp;
                    case 'Kontras Rendah'
                        Img_kr = imadjust(Img,[80/225 160/225],[80/255 175/255]);
                        imshow(Img_kr,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Kontras Rendah')
                        app.Y = Img_kr;
                    case 'Kontras Tinggi'
                        Img_kt = imadjust(Img,[80/225 160/225],[0/255 255/255]);
                        imshow(Img_kt,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Kontras Tinggi')
                        app.Y = Img_kt;
                    case 'Brightness Tinggi'
                        Img_bt = imadjust(Img,[0 1],[0.5 1]);
                        imshow(Img_bt,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Brightness Tinggi')
                        app.Y = Img_bt;
                    case 'Brightness Rendah'
                        Img_br = imadjust(Img,[0 1],[0 0.5]);
                        imshow(Img_br,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Brightness Rendah')
                        app.Y = Img_br;
                end
            app.InformasiResolusiPanel.Visible='off';
        end

        % Value changed function: SimpanPerubahanButton
        function SimpanPerubahanButtonValueChanged(app, event)
            value = app.SimpanPerubahanButton.Value;

            Img = app.Y;
            app.SimpanPerubahanButton.Value = false;

            imshow(Img,'Parent',app.UIAxes)
            title(app.UIAxes,'Citra Asli')
            imshow(Img,'Parent',app.UIAxes_2)
            title(app.UIAxes,'Citra Terproses')
            app.TidakAdaButton.Value = true;
            app.TidakAdaButton_2.Value = true;
            app.TidakAdaButton_3.Value = true;
            app.TidakAdaButton_5.Value = true;

            app.X = Img;
        end

        % Value changed function: SimpanFileButton
        function SimpanFileButtonValueChanged(app, event)
            value = app.SimpanFileButton.Value;
            Img = app.Y;
            app.SimpanFileButton.Value = false;

            % Get the name of the file that the user wants to save.
            % Note, if you're saving an image you can use imsave() instead of uiputfile().
            startingFolder = userpath % Or "pwd" or wherever you want.
            defaultFileName = fullfile(startingFolder, '*.png');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
            if baseFileName == 0
	            % User clicked the Cancel button.
	            return;
            end
            fullFileName = fullfile(folder, baseFileName)
            imwrite(Img, fullFileName);
        end

        % Value changed function: NilaiderajatSpinner
        function NilaiderajatSpinnerValueChanged(app, event)
            value = app.NilaiderajatSpinner.Value
        end

        % Value changing function: NilaiderajatSpinner
        function NilaiderajatSpinnerValueChanging(app, event)
            Img = app.X;
            ubahValue = event.Value;

            Img_rotasi = imrotate(Img,ubahValue,'bilinear','crop');
            imshow(Img_rotasi,'Parent',app.UIAxes_2)
            title(app.UIAxes_2,'Citra Rotasi ke Kiri')
            app.Y = Img_rotasi;
            app.RotasiKiriButton.Value = true;
            app.NilaiderajatSpinner_3.Value = 0;
            app.InformasiResolusiPanel.Visible='off';
        end

        % Callback function
        function NilaiderajatSpinner_2ValueChanging(app, event)
            
        end

        % Value changing function: NilaiderajatSpinner_3
        function NilaiderajatSpinner_3ValueChanging(app, event)
            Img = app.X;
            ubahValue = event.Value;

            Img_rotasi = imrotate(Img,-ubahValue,'bilinear','crop');
            imshow(Img_rotasi,'Parent',app.UIAxes_2)
            title(app.UIAxes_2,'Citra Rotasi ke Kanan')
            app.Y = Img_rotasi;
            app.RotasiKananButton.Value = true;
            app.NilaiderajatSpinner.Value = 0;
            app.InformasiResolusiPanel.Visible='off';
        end

        % Value changing function: InputSpinner
        function InputSpinnerValueChanging(app, event)
            Img = app.X;
            changingValue = event.Value;

            if changingValue == 0
                hflip = flipdim(Img, changingValue+4)
                imshow(hflip,'Parent',app.UIAxes_2)
                title(app.UIAxes_2,'Citra Horizontal Flip')
            else
                hflip = flipdim(Img ,2);
                imshow(hflip,'Parent',app.UIAxes_2)
                title(app.UIAxes_2,'Citra Horizontal Flip')
            end
            app.Y = hflip;
            app.HorizontalFlipButton.Value = true;
            app.InputSpinner_2.Value = 0;
            app.InformasiResolusiPanel.Visible='off';
        end

        % Value changing function: InputSpinner_2
        function InputSpinner_2ValueChanging(app, event)
            Img = app.X;
            changingValue = event.Value;

            if changingValue == 0
                vflip = flipdim(Img ,changingValue+4);
                imshow(vflip,'Parent',app.UIAxes_2)
                title(app.UIAxes_2,'Citra Horizontal Flip')
            else
                vflip = flipdim(Img ,1);
                imshow(vflip,'Parent',app.UIAxes_2)
                title(app.UIAxes_2,'Citra Horizontal Flip')
            end
            
            
            app.Y = vflip;
            app.VerticalFlipButton.Value = true;
            app.InputSpinner.Value = 0;
            app.InformasiResolusiPanel.Visible='off';
        end

        % Value changing function: ResolusiBaruSlider
        function ResolusiBaruSliderValueChanging(app, event)
            value = app.ResolusiBaruSlider.Value
            
        end

        % Value changed function: ResolusiBaruSlider
        function ResolusiBaruSliderValueChanged(app, event)
            Img = app.X;
            changingValue = event.Value;
            desimal_limit = round(changingValue,3)
            value_str = string(desimal_limit*100);
            
            Img_resize = imresize(Img, changingValue);
            imshow(Img_resize,'Parent',app.UIAxes_2)
            title(app.UIAxes_2, 'Citra Resolusi Baru')
            app.EditField.Value = value_str + "%";

            app.Y = Img_resize;
            imwrite(Img_resize,'G:\Aplikasi MATLAB\temp\resize.png')
            infoI2 = imfinfo('G:\Aplikasi MATLAB\temp\resize.png');
            width = string(infoI2.Width);
            height = string(infoI2.Height);

            app.InformasiResolusiPanel.Visible='on';
            app.ResizeEditField.Value = width + 'x' + height;
        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterButtonGroup.SelectedObject.Text
            
            Img = app.X;
            filename = app.name;
            R = Img(:,:,1);
            G = Img(:,:,2);
            B = Img(:,:,3);

                switch selectedButton
                    case 'Tidak Ada'
                        imshow(Img,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Citra Tanpa Filter')
                        app.Y = Img;
                    case 'Filter Median'
                        median = medfilt3(Img);
                        imshow(median,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Hasil Filter Median')
                        app.Y = median;
                    case 'Filter Batas'
                        modus = modefilt(Img);
                        imshow(modus,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Hasil Filter Modus')
                        app.Y = modus;
                    case 'Filter Pererataan'
                        a = rgb2gray(Img);
                        [m,n] = size(a);
                        output = zeros(m,n);
                        for i=1:m
                            for j=1:n
                                rmin = max(1,i-1);
                                rmax = min(m,i+1);
                                cmin = max(1, j-1);
                                cmax = min(n, j+1);

                                temp = a(rmin:rmax, cmin:cmax);

                                output(i,j) = mean(temp(:));
                            end
                        end
                        output = uint8(output);

                        imshow(output,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Hasil Filter Pererataan')
                        app.Y = output;
                end
            
        end

        % Value changed function: SimpanPerubahanButton_2
        function SimpanPerubahanButton_2ValueChanged(app, event)
            value = app.SimpanPerubahanButton_2.Value;

            Img = app.Y;
            app.SimpanPerubahanButton_2.Value = false;

            imshow(Img,'Parent',app.UIAxes)
            title(app.UIAxes,'Citra Asli')
            imshow(Img,'Parent',app.UIAxes_2)
            title(app.UIAxes,'Citra Terproses')
            app.TidakAdaButton.Value = true;
            app.TidakAdaButton_2.Value = true;
            app.TidakAdaButton_3.Value = true;
            app.TidakAdaButton_5.Value = true;

            app.X = Img;
        end

        % Value changed function: SimpanFileButton_2
        function SimpanFileButton_2ValueChanged(app, event)
            value = app.SimpanFileButton_2.Value;
            Img = app.Y;
            app.SimpanFileButton_2.Value = false;

            % Get the name of the file that the user wants to save.
            % Note, if you're saving an image you can use imsave() instead of uiputfile().
            startingFolder = userpath % Or "pwd" or wherever you want.
            defaultFileName = fullfile(startingFolder, '*.png');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
            if baseFileName == 0
	            % User clicked the Cancel button.
	            return;
            end
            fullFileName = fullfile(folder, baseFileName)
            imwrite(Img, fullFileName);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {899, 899};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.PilihGambarMenu = uimenu(app.FileMenu);
            app.PilihGambarMenu.MenuSelectedFcn = createCallbackFcn(app, @PilihGambarMenuSelected, true);
            app.PilihGambarMenu.Accelerator = 'O';
            app.PilihGambarMenu.Text = 'Pilih Gambar ....';
            app.PilihGambarMenu.Tag = 'File1';
            app.ResetMenu = uimenu(app.FileMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Accelerator = 'Q';
            app.ResetMenu.Text = 'Reset';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PenyesuaianTab = uitab(app.TabGroup);
            app.PenyesuaianTab.Title = 'Penyesuaian';
            app.PenyesuaianCitraButtonGroup = uibuttongroup(app.PenyesuaianTab);
            app.PenyesuaianCitraButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PenyesuaianCitraButtonGroupSelectionChanged, true);
            app.PenyesuaianCitraButtonGroup.Title = 'Penyesuaian Citra';
            app.TidakAdaButton = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.TidakAdaButton.Text = 'Tidak Ada';
            app.TidakAdaButton.Value = true;
            app.GrayscaleButton = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.GrayscaleButton.Enable = 'off';
            app.GrayscaleButton.Text = 'Grayscale';
            app.SharpeningButton = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.SharpeningButton.Enable = 'off';
            app.SharpeningButton.Text = 'Sharpening';
            app.KontrasRendahButton = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.KontrasRendahButton.Enable = 'off';
            app.KontrasRendahButton.Text = 'Kontras Rendah';
            app.BrightnessTinggiButton = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.BrightnessTinggiButton.Enable = 'off';
            app.BrightnessTinggiButton.Text = 'Brightness Tinggi';
            app.BrightnessRendahButton = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.BrightnessRendahButton.Enable = 'off';
            app.BrightnessRendahButton.Text = 'Brightness Rendah';
            app.RedButton = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.RedButton.Enable = 'off';
            app.RedButton.Text = 'Red';
            app.GreenButton = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.GreenButton.Enable = 'off';
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.BlueButton.Enable = 'off';
            app.BlueButton.Text = 'Blue';
            app.KontrasTinggiButton_2 = uiradiobutton(app.PenyesuaianCitraButtonGroup);
            app.KontrasTinggiButton_2.Enable = 'off';
            app.KontrasTinggiButton_2.Text = 'Kontras Tinggi';
            app.SimpanPerubahanButton = uibutton(app.PenyesuaianTab, 'state');
            app.SimpanPerubahanButton.ValueChangedFcn = createCallbackFcn(app, @SimpanPerubahanButtonValueChanged, true);
            app.SimpanPerubahanButton.Enable = 'off';
            app.SimpanPerubahanButton.Text = 'Simpan Perubahan';
            app.SimpanFileButton = uibutton(app.PenyesuaianTab, 'state');
            app.SimpanFileButton.ValueChangedFcn = createCallbackFcn(app, @SimpanFileButtonValueChanged, true);
            app.SimpanFileButton.Enable = 'off';
            app.SimpanFileButton.Text = 'Simpan File ....';
            app.FilterButtonGroup = uibuttongroup(app.PenyesuaianTab);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.FilterMedianButton = uiradiobutton(app.FilterButtonGroup);
            app.FilterMedianButton.Enable = 'off';
            app.FilterMedianButton.Text = 'Filter Median';
            app.TidakAdaButton_5 = uiradiobutton(app.FilterButtonGroup);
            app.TidakAdaButton_5.Text = 'Tidak Ada';
            app.TidakAdaButton_5.Value = true;
            app.FilterBatasButton = uiradiobutton(app.FilterButtonGroup);
            app.FilterBatasButton.Enable = 'off';
            app.FilterBatasButton.Text = 'Filter Batas';
            app.FilterPererataanButton = uiradiobutton(app.FilterButtonGroup);
            app.FilterPererataanButton.Enable = 'off';
            app.FilterPererataanButton.Text = 'Filter Pererataan';
            app.TransformasiTab = uitab(app.TabGroup);
            app.TransformasiTab.Title = 'Transformasi';
            app.RotasiButtonGroup = uibuttongroup(app.TransformasiTab);
            app.RotasiButtonGroup.Title = 'Rotasi';
            app.TidakAdaButton_2 = uiradiobutton(app.RotasiButtonGroup);
            app.TidakAdaButton_2.Visible = 'off';
            app.TidakAdaButton_2.Text = 'Tidak Ada';
            app.TidakAdaButton_2.Value = true;
            app.RotasiKiriButton = uiradiobutton(app.RotasiButtonGroup);
            app.RotasiKiriButton.Text = 'Rotasi Kiri ';
            app.NilaiderajatSpinnerLabel = uilabel(app.RotasiButtonGroup);
            app.NilaiderajatSpinnerLabel.Text = 'Nilai derajat';
            app.NilaiderajatSpinner = uispinner(app.RotasiButtonGroup);
            app.NilaiderajatSpinner.ValueChangingFcn = createCallbackFcn(app, @NilaiderajatSpinnerValueChanging, true);
            app.NilaiderajatSpinner.Limits = [0 360];
            app.NilaiderajatSpinner.ValueChangedFcn = createCallbackFcn(app, @NilaiderajatSpinnerValueChanged, true);
            app.NilaiderajatSpinner.Enable = 'off';
            app.RotasiKananButton = uiradiobutton(app.RotasiButtonGroup);
            app.RotasiKananButton.Text = 'Rotasi Kanan ';
            app.NilaiderajatSpinner_3Label = uilabel(app.RotasiButtonGroup);
            app.NilaiderajatSpinner_3Label.Text = 'Nilai derajat';
            app.NilaiderajatSpinner_3 = uispinner(app.RotasiButtonGroup);
            app.NilaiderajatSpinner_3.ValueChangingFcn = createCallbackFcn(app, @NilaiderajatSpinner_3ValueChanging, true);
            app.NilaiderajatSpinner_3.Limits = [0 360];
            app.NilaiderajatSpinner_3.Enable = 'off';
            app.FlipButtonGroup = uibuttongroup(app.TransformasiTab);
            app.FlipButtonGroup.Title = 'Flip';
            app.TidakAdaButton_3 = uiradiobutton(app.FlipButtonGroup);
            app.TidakAdaButton_3.Visible = 'off';
            app.TidakAdaButton_3.Text = 'Tidak Ada';
            app.TidakAdaButton_3.Value = true;
            app.HorizontalFlipButton = uiradiobutton(app.FlipButtonGroup);
            app.HorizontalFlipButton.Text = 'Horizontal Flip';
            app.InputSpinnerLabel = uilabel(app.FlipButtonGroup);
            app.InputSpinnerLabel.Text = 'Input';
            app.InputSpinner = uispinner(app.FlipButtonGroup);
            app.InputSpinner.ValueChangingFcn = createCallbackFcn(app, @InputSpinnerValueChanging, true);
            app.InputSpinner.Limits = [0 1];
            app.InputSpinner.Enable = 'off';
            app.VerticalFlipButton = uiradiobutton(app.FlipButtonGroup);
            app.VerticalFlipButton.Text = 'Vertical Flip';
            app.InputSpinner_2Label = uilabel(app.FlipButtonGroup);
            app.InputSpinner_2Label.Text = 'Input';
            app.InputSpinner_2 = uispinner(app.FlipButtonGroup);
            app.InputSpinner_2.ValueChangingFcn = createCallbackFcn(app, @InputSpinner_2ValueChanging, true);
            app.InputSpinner_2.Limits = [0 1];
            app.InputSpinner_2.Enable = 'off';
            app.efekmati1efekhidupLabel = uilabel(app.FlipButtonGroup);
            app.efekmati1efekhidupLabel.Enable = 'off';
            app.efekmati1efekhidupLabel.Text = {'0 = efek mati'; '1 = efek hidup'};
            app.SesuaikanUkuranButtonGroup = uibuttongroup(app.TransformasiTab);
            app.SesuaikanUkuranButtonGroup.Title = 'Sesuaikan Ukuran';
            app.TidakAdaButton_4 = uiradiobutton(app.SesuaikanUkuranButtonGroup);
            app.TidakAdaButton_4.Visible = 'off';
            app.TidakAdaButton_4.Text = 'Tidak Ada';
            app.TidakAdaButton_4.Value = true;
            app.ResolusiBaruSliderLabel = uilabel(app.SesuaikanUkuranButtonGroup);
            app.ResolusiBaruSliderLabel.Text = {'Resolusi'; 'Baru'};
            app.ResolusiBaruSlider = uislider(app.SesuaikanUkuranButtonGroup);
            app.ResolusiBaruSlider.Limits = [1e-06 3];
            app.ResolusiBaruSlider.MajorTicks = [1 3];
            app.ResolusiBaruSlider.MajorTickLabels = {'100%', '300%'};
            app.ResolusiBaruSlider.ValueChangedFcn = createCallbackFcn(app, @ResolusiBaruSliderValueChanged, true);
            app.ResolusiBaruSlider.ValueChangingFcn = createCallbackFcn(app, @ResolusiBaruSliderValueChanging, true);
            app.ResolusiBaruSlider.MinorTicks = [0 1 2 3 4 5];
            app.ResolusiBaruSlider.Value = 1e-06;
            app.EditField = uieditfield(app.SesuaikanUkuranButtonGroup, 'text');
            app.SimpanPerubahanButton_2 = uibutton(app.TransformasiTab, 'state');
            app.SimpanPerubahanButton_2.ValueChangedFcn = createCallbackFcn(app, @SimpanPerubahanButton_2ValueChanged, true);
            app.SimpanPerubahanButton_2.Enable = 'off';
            app.SimpanPerubahanButton_2.Text = 'Simpan Perubahan';
            app.SimpanFileButton_2 = uibutton(app.TransformasiTab, 'state');
            app.SimpanFileButton_2.ValueChangedFcn = createCallbackFcn(app, @SimpanFileButton_2ValueChanged, true);
            app.SimpanFileButton_2.Enable = 'off';
            app.SimpanFileButton_2.Text = 'Simpan File ....';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Citra Asli')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Citra Terproses')
            app.UIAxes_2.XLim = [0 1];
            app.UIAxes_2.YLim = [0 1];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.CitraAsliLabel = uilabel(app.RightPanel);
            app.CitraAsliLabel.Text = 'Citra Asli';
            app.CitraTerprosesLabel = uilabel(app.RightPanel);
            app.CitraTerprosesLabel.Text = 'Citra Terproses';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'notfound.png');
            app.Image_2 = uiimage(app.RightPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'notfound.png');
            app.InformasiResolusiPanel = uipanel(app.RightPanel);
            app.InformasiResolusiPanel.Title = 'Informasi Resolusi';
            app.InformasiResolusiPanel.Visible = 'off';
            app.ResizeEditFieldLabel = uilabel(app.InformasiResolusiPanel);
            app.ResizeEditFieldLabel.Text = 'Resize';
            app.ResizeEditField = uieditfield(app.InformasiResolusiPanel, 'text');
            app.AsliEditFieldLabel = uilabel(app.InformasiResolusiPanel);
            app.AsliEditFieldLabel.Text = 'Asli';
            app.AsliEditField = uieditfield(app.InformasiResolusiPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end